classdef solver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SolverUIFigure               matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadMenu                     matlab.ui.container.Menu
        InputsPanel                  matlab.ui.container.Panel
        MethodDropDownLabel          matlab.ui.control.Label
        MethodDropDown               matlab.ui.control.DropDown
        NumberofEquationsEditFieldLabel  matlab.ui.control.Label
        NumberofEquationsEditField   matlab.ui.control.NumericEditField
        EquationsTextAreaLabel       matlab.ui.control.Label
        EquationsTextArea            matlab.ui.control.TextArea
        MaxIterationsEditFieldLabel  matlab.ui.control.Label
        MaxIterationsEditField       matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel      matlab.ui.control.Label
        ToleranceEditField           matlab.ui.control.NumericEditField
        SolveButton                  matlab.ui.control.Button
        VariablesEditField           matlab.ui.control.EditField
        VariablesLabel               matlab.ui.control.Label
        InitialGuessesEditField      matlab.ui.control.EditField
        InitialGuessesLabel          matlab.ui.control.Label
        OutputsPanel                 matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
        ExcutionTimeEditFieldLabel   matlab.ui.control.Label
        ExcutionTimeEditField        matlab.ui.control.NumericEditField
        IterationsEditFieldLabel     matlab.ui.control.Label
        IterationsEditField          matlab.ui.control.NumericEditField
        ResultsTextAreaLabel         matlab.ui.control.Label
        ResultsTextArea              matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.SolverUIFigure, 'center');
            clear;
            clc;
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            if value == "Gauss-Seidel Method" || value == "All"
                app.InitialGuessesEditField.Enable = true;
                app.InitialGuessesLabel.Enable = true;
                app.MaxIterationsEditField.Enable = true;
                app.MaxIterationsEditFieldLabel.Enable = true;
                app.ToleranceEditField.Enable = true;
                app.ToleranceEditFieldLabel.Enable = true;
            else
                app.InitialGuessesEditField.Enable = false;
                app.InitialGuessesLabel.Enable = false;
                app.MaxIterationsEditField.Enable = false;
                app.MaxIterationsEditFieldLabel.Enable = false;
                app.ToleranceEditField.Enable = false;
                app.ToleranceEditFieldLabel.Enable = false;
            end
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            [error, n, method, symbols, equations, initialConditions, max_iter, epsilon, isIterative] = readfile();
            if error == 0
                [error] = validateInputs(n, method, initialConditions, max_iter, epsilon, isIterative);
                if error == 0
                    app.MethodDropDown.Value = method;
                    app.NumberofEquationsEditField.Value = str2double(n);
                    app.VariablesEditField.Value = symbols;
                    app.EquationsTextArea.Value = equations;
                    if isIterative
                        app.InitialGuessesEditField.Value = initialConditions;
                        app.MaxIterationsEditField.Value = str2double(max_iter);
                        app.ToleranceEditField.Value = str2double(epsilon);
                    end
                else
                    errordlg(error);
                end
            else
                errordlg(error);
            end
            MethodDropDownValueChanged(app);
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            method = app.MethodDropDown.Value;
            n = num2str(app.NumberofEquationsEditField.Value);
            variables = app.VariablesEditField.Value;
            equations = app.EquationsTextArea.Value;
            isIterative = false;
            initialConditions = app.InitialGuessesEditField.Value;
            maxIterations = num2str(app.MaxIterationsEditField.Value);
            tolerance = num2str(app.ToleranceEditField.Value);
            if method == "Gauss-Seidel Method" || method == "All"
                isIterative = true;
            end
            [error] = validateInputs(n, method, initialConditions, maxIterations, tolerance, isIterative);
            if error == 0
                [error, n, initialConditions, maxIterations, tolerance, coeffsMatrix, resultsMatrix, variables] = parseInput(n, isIterative, initialConditions, maxIterations, tolerance, equations, variables);
                if error == 0
                    isAll = false;
                    isSingular = false;
                    iterations = 0;
                    switch (method)
                        case "Gauss Elimination"
                            tic;
                            [X, isSingular] = gaussElimination(coeffsMatrix, resultsMatrix, n, 0.00001);
                            app.ExcutionTimeEditField.Value = toc;
                            app.IterationsEditField.Value = iterations;
                        case "Gauss-Jordan Elimination"
                            tic;
                            [X, isSingular] = gaussJordanElimination(coeffsMatrix, resultsMatrix, n, 0.00001);
                            app.ExcutionTimeEditField.Value = toc;
                            app.IterationsEditField.Value = iterations;
                        case "LU Decomposition"
                            tic;
                            [X, isSingular] = LUdecomposition(coeffsMatrix, resultsMatrix, n, 0.00001);
                            app.ExcutionTimeEditField.Value = toc;
                            app.IterationsEditField.Value = iterations;
                        case "Gauss-Seidel Method"
                            tic;
                            [X, iterations, data] = gaussSeidel(coeffsMatrix, resultsMatrix, initialConditions, n, maxIterations, tolerance);
                            app.ExcutionTimeEditField.Value = toc;
                            app.IterationsEditField.Value = iterations;
                        case "All"
                            tic;
                            [Xgauss, temp] = gaussElimination(coeffsMatrix, resultsMatrix, n, tolerance);
                            isSingular = temp;
                            [XgaussJordan, temp] = gaussJordanElimination(coeffsMatrix, resultsMatrix, n, tolerance);
                            isSingular = isSingular && temp;
                            [XluDecomp, temp] = LUdecomposition(coeffsMatrix, resultsMatrix, n, tolerance);
                            isSingular = isSingular && temp;
                            [XgaussSeidel, iterations, data] = gaussSeidel(coeffsMatrix, resultsMatrix, initialConditions, n, maxIterations, tolerance);
                            app.ExcutionTimeEditField.Value = toc;
                            app.IterationsEditField.Value = iterations;
                            isAll = true;
                    end
                    if ~isAll
                        if ~isIterative
                            if ~isSingular
                                app.ResultsTextArea.Value = formatAnswer(X, variables);
                            else
                                errordlg("Function can not be solved");
                            end
                        else
                            app.ResultsTextArea.Value = formatAnswer(X, variables);
                            name = cell(1, 2*n);
                            form = cell(1, 2*n);
                            for i = 1 : n
                                pre = {'ea(%)'};
                                name(1, 2*i-1) = variables(i);
                                name(1, 2*i) = pre;
                                form(2*i-1) = {'long'};
                                form(2*i) = {'bank'};
                            end
                            app.UITable.ColumnName = name;
                            app.UITable.Data = data;
                            app.UITable.ColumnFormat = form;
                        end
                    else
                        app.ResultsTextArea.Value = formatAllAnswers(Xgauss, XgaussJordan, XluDecomp, XgaussSeidel, variables);
                        name = cell(1, 2*n);
                        form = cell(1, 2*n);
                        for i = 1 : n
                            pre = {'ea(%)'};
                            name(1, 2*i-1) = variables(i);
                            name(1, 2*i) = pre;
                            form(2*i-1) = {'long'};
                            form(2*i) = {'bank'};
                        end
                        app.UITable.ColumnName = name;
                        app.UITable.Data = data;
                        app.UITable.ColumnFormat = form;
                    end
                    % plot
                    if method == "Gauss-Seidel Method" || method == "All"
                        x = 1 : iterations;
                        plot(app.UIAxes, x, data(:, 2*1-1), 'LineWidth', 2, 'DisplayName', string(variables(1)));
                        for i = 2 : n
                            hold(app.UIAxes, 'on');
                            plot(app.UIAxes, x, data(:, 2*i-1), 'LineWidth', 2, 'DisplayName', string(variables(i)));
                            hold(app.UIAxes, 'off');
                        end
                        legend(app.UIAxes, 'show');
                    end
                else
                    errordlg(error);
                end
            else
                errordlg(error);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SolverUIFigure = uifigure;
            app.SolverUIFigure.Name = 'Systems of Linear Equations Solver';
            app.FileMenu = uimenu(app.SolverUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.InputsPanel = uipanel(app.SolverUIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.MethodDropDownLabel = uilabel(app.InputsPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.InputsPanel);
            app.MethodDropDown.Items = {'Gauss Elimination', 'Gauss-Jordan Elimination', 'LU Decomposition', 'Gauss-Seidel Method', 'All'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Gauss Elimination';
            app.NumberofEquationsEditFieldLabel = uilabel(app.InputsPanel);
            app.NumberofEquationsEditFieldLabel.Text = 'Number of Equations';
            app.NumberofEquationsEditField = uieditfield(app.InputsPanel, 'numeric');
            app.NumberofEquationsEditField.Limits = [0 999];
            app.EquationsTextAreaLabel = uilabel(app.InputsPanel);
            app.EquationsTextAreaLabel.Text = 'Equations (One equation per line)';
            app.EquationsTextArea = uitextarea(app.InputsPanel);
            app.MaxIterationsEditFieldLabel = uilabel(app.InputsPanel);
            app.MaxIterationsEditFieldLabel.Enable = 'off';
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.InputsPanel, 'numeric');
            app.MaxIterationsEditField.Limits = [0 999];
            app.MaxIterationsEditField.Enable = 'off';
            app.MaxIterationsEditField.Value = 50;
            app.ToleranceEditFieldLabel = uilabel(app.InputsPanel);
            app.ToleranceEditFieldLabel.Enable = 'off';
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.InputsPanel, 'numeric');
            app.ToleranceEditField.Limits = [0 1];
            app.ToleranceEditField.Enable = 'off';
            app.ToleranceEditField.Value = 1e-05;
            app.SolveButton = uibutton(app.InputsPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.VariablesEditField = uieditfield(app.InputsPanel, 'text');
            app.VariablesLabel = uilabel(app.InputsPanel);
            app.VariablesLabel.Text = 'Variables (Enter space-separated values)';
            app.InitialGuessesEditField = uieditfield(app.InputsPanel, 'text');
            app.InitialGuessesEditField.Enable = 'off';
            app.InitialGuessesLabel = uilabel(app.InputsPanel);
            app.InitialGuessesLabel.Enable = 'off';
            app.InitialGuessesLabel.Text = 'Initial Guesses (Enter space-separated values)';
            app.OutputsPanel = uipanel(app.SolverUIFigure);
            app.OutputsPanel.Title = 'Outputs';
            app.UITable = uitable(app.OutputsPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UIAxes = uiaxes(app.OutputsPanel);
            title(app.UIAxes, 'Title')
            app.ExcutionTimeEditFieldLabel = uilabel(app.OutputsPanel);
            app.ExcutionTimeEditFieldLabel.Text = 'Excution Time';
            app.ExcutionTimeEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.ExcutionTimeEditField.Editable = 'off';
            app.IterationsEditFieldLabel = uilabel(app.OutputsPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.IterationsEditField.Editable = 'off';
            app.ResultsTextAreaLabel = uilabel(app.OutputsPanel);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.OutputsPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = solver

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SolverUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SolverUIFigure)
        end
    end
end